classdef Chappers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        NewGameButton               matlab.ui.control.Button
        PlayAIButton                matlab.ui.control.Button
        AlgorithmDepthSpinnerLabel  matlab.ui.control.Label
        AlgorithmDepthSpinner       matlab.ui.control.Spinner
        AIPlayerSwitchLabel         matlab.ui.control.Label
        AIPlayerSwitch              matlab.ui.control.Switch
    end

    
    properties (Access = private)
        res % Resolution
        selected % Is a piece selected?
        piece
        depth
        player
    end
    
    methods (Access = private)
        
        function drawBoardApp(app)
            imageBoard = drawBoard;
            imagesc(app.UIAxes, imageBoard)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            NewGameButtonPushed(app);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            newGame();
            imageBoard = drawBoard;
            % Build custom colormap
            % color order is: player1, player2, boardDark, boardLight, king color
            colors = [0, 0, 0; 255, 0, 0; 112, 72, 19;240, 221, 161; 255, 196, 0];
            colors = colors./255;
            
            colormap(app.UIAxes, colors)
            imagesc(app.UIAxes, imageBoard)
            app.UIAxes.XLim = [1, length(imageBoard)];
            app.UIAxes.YLim = [1, length(imageBoard)];
            app.UIAxes.Interactions = [];
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            
            app.res = length(imageBoard);
            app.selected = false;
            app.depth = app.AlgorithmDepthSpinner.Value;
            if app.AIPlayerSwitch.Value == 'Player 1'
                app.player = true;
            else
                app.player = false;
            end


        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            rawMouse = get(app.UIAxes, 'CurrentPoint');
            mousePos(1) = rawMouse(3);
            mousePos(2) = rawMouse(1);
            
            if sum(mousePos >= 800 | mousePos <= 0) == 0
                if ~app.selected
                    app.selected = ~app.selected;
                    app.piece = fix(mousePos/(app.res/8))+1;
                else
                    place = fix(mousePos/(app.res/8))+1;
                    playHuman(app.piece, place);
                    app.drawBoardApp;
                    app.selected = false;
                end
            else
                app.selected = false;
            end
            
        end

        % Button pushed function: PlayAIButton
        function PlayAIButtonPushed(app, event)
            generateMovesPlayer(app.player);
            [eval, bestMove] = minimax(app.depth, app.player);
            playMove(bestMove);
            app.drawBoardApp;
        end

        % Value changed function: AlgorithmDepthSpinner
        function AlgorithmDepthSpinnerValueChanged(app, event)
            value = app.AlgorithmDepthSpinner.Value;
            app.depth = value;
        end

        % Value changed function: AIPlayerSwitch
        function AIPlayerSwitchValueChanged(app, event)
            value = app.AIPlayerSwitch.Value;
            if value == 'Player 1'
                app.player = true;
            else
                app.player = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayAIButton = uibutton(app.UIFigure, 'push');
            app.PlayAIButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAIButtonPushed, true);
            app.PlayAIButton.Text = 'Play AI';
            app.AlgorithmDepthSpinnerLabel = uilabel(app.UIFigure);
            app.AlgorithmDepthSpinnerLabel.Text = 'Algorithm Depth';
            app.AlgorithmDepthSpinner = uispinner(app.UIFigure);
            app.AlgorithmDepthSpinner.Limits = [1 10];
            app.AlgorithmDepthSpinner.RoundFractionalValues = 'on';
            app.AlgorithmDepthSpinner.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDepthSpinnerValueChanged, true);
            app.AlgorithmDepthSpinner.Value = 1;
            app.AIPlayerSwitchLabel = uilabel(app.UIFigure);
            app.AIPlayerSwitchLabel.Text = 'AI Player';
            app.AIPlayerSwitch = uiswitch(app.UIFigure, 'slider');
            app.AIPlayerSwitch.Items = {'Player 2', 'Player 1'};
            app.AIPlayerSwitch.ValueChangedFcn = createCallbackFcn(app, @AIPlayerSwitchValueChanged, true);
            app.AIPlayerSwitch.Value = 'Player 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chappers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end