classdef ChappersBackup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        TurnLamp                     matlab.ui.control.Lamp
        NewGameButton                matlab.ui.control.Button
        PlayAIButton                 matlab.ui.control.Button
        AlgorithnmDepthSpinnerLabel  matlab.ui.control.Label
        AlgorithnmDepthSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        res % Resolution
        selected % Is a piece selected?
        piece
        depth
    end
    
    methods (Access = private)
        
        function drawBoardApp(app)
            imageBoard = drawBoard;
            imagesc(app.UIAxes, imageBoard)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            NewGameButtonPushed(app);
            app.TurnLamp.Enable = 'on';
            app.TurnLamp.Color = [0 0 0];
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            newGame();
            imageBoard = drawBoard;
            % Build custom colormap
            % color order is: player1, player2, boardDark, boardLight, king color
            colors = [0, 0, 0; 255, 0, 0; 112, 72, 19;240, 221, 161; 255, 196, 0];
            colors = colors./255;
            
            colormap(app.UIAxes, colors)
            imagesc(app.UIAxes, imageBoard)
            app.UIAxes.XLim = [1, length(imageBoard)];
            app.UIAxes.YLim = [1, length(imageBoard)];
            app.UIAxes.Interactions = [];
            app.UIAxes.PlotBoxAspectRatio = [1 1 1];
            
            app.res = length(imageBoard);
            app.selected = false;


        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            rawMouse = get(app.UIAxes, 'CurrentPoint');
            mousePos(1) = rawMouse(3);
            mousePos(2) = rawMouse(1);
            
            if sum(mousePos >= 800 | mousePos <= 0) == 0
                if ~app.selected
                    app.selected = ~app.selected;
                    app.piece = fix(mousePos/(app.res/8))+1;
                else
                    place = fix(mousePos/(app.res/8))+1;
                    playHuman(app.piece, place);
                    app.drawBoardApp;
                    app.selected = false;
                end
            else
                app.selected = false;
            end
            
        end

        % Button pushed function: PlayAIButton
        function PlayAIButtonPushed(app, event)
            generateMovesPlayer(false);
            [eval, bestMove] = minimax(app.depth, false);
            playMove(bestMove, false);
            app.drawBoardApp;
        end

        % Value changed function: AlgorithnmDepthSpinner
        function AlgorithnmDepthSpinnerValueChanged(app, event)
            value = app.AlgorithnmDepthSpinner.Value;
            app.depth = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TurnLamp = uilamp(app.UIFigure);
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.PlayAIButton = uibutton(app.UIFigure, 'push');
            app.PlayAIButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAIButtonPushed, true);
            app.PlayAIButton.Text = 'Play AI';
            app.AlgorithnmDepthSpinnerLabel = uilabel(app.UIFigure);
            app.AlgorithnmDepthSpinnerLabel.Text = 'Algorithnm Depth';
            app.AlgorithnmDepthSpinner = uispinner(app.UIFigure);
            app.AlgorithnmDepthSpinner.Limits = [1 10];
            app.AlgorithnmDepthSpinner.RoundFractionalValues = 'on';
            app.AlgorithnmDepthSpinner.ValueChangedFcn = createCallbackFcn(app, @AlgorithnmDepthSpinnerValueChanged, true);
            app.AlgorithnmDepthSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChappersBackup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end